classdef MainView < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        OptionsMenu            matlab.ui.container.Menu
        RestartAppMenu         matlab.ui.container.Menu
        Toolbar                matlab.ui.container.Toolbar
        PushTool               matlab.ui.container.toolbar.PushTool
        addNodeBtn             matlab.ui.container.toolbar.PushTool
        addElementBtn          matlab.ui.container.toolbar.PushTool
        PushTool2              matlab.ui.container.toolbar.PushTool
        PushTool3              matlab.ui.container.toolbar.PushTool
        ToggleTool             matlab.ui.container.toolbar.ToggleTool
        Tree                   matlab.ui.container.Tree
        MaterialsNode          matlab.ui.container.TreeNode
        Nodes                  matlab.ui.container.TreeNode
        ElementsNode           matlab.ui.container.TreeNode
        ForcesNode_2           matlab.ui.container.TreeNode
        ResultsNode            matlab.ui.container.TreeNode
        ForcesNode             matlab.ui.container.TreeNode
        DisplacementsNode      matlab.ui.container.TreeNode
        StressNode             matlab.ui.container.TreeNode
        UIAxes                 matlab.ui.control.UIAxes
        ContextMenu            matlab.ui.container.ContextMenu
        AddMaterial            matlab.ui.container.Menu
        ContextMenu2           matlab.ui.container.ContextMenu
        AddElementMenu         matlab.ui.container.Menu
        ContextMenu3           matlab.ui.container.ContextMenu
        AddNodeMenu            matlab.ui.container.Menu
        ContextMenu4           matlab.ui.container.ContextMenu
        AddForceMenu           matlab.ui.container.Menu
        ContextMenu5           matlab.ui.container.ContextMenu
        RunModelCalculateMenu  matlab.ui.container.Menu
    end

    
    properties (Access = private)
        ui = UI(); % Instanciando a interface de usuário
    end
    
    properties (Access = public)
        materials = {};
        boundaryForcesList = [];
        boundaryNodesList = [];
        forces;
        displacements;
        elements = {};
        lines = [];
        lastElementType = NaN;
        lastMaterial = NaN;
        margin = 0.25;
    end
    
    methods (Access = public)
        
        function addNodeValue(app,coordinates,type)
            
            type2 = type;
            
            switch type
                case "Pinned"
                    type = NodeType.PINNED;
                    
                case "Free"
                    type = NodeType.FREE;
                    
                case "RollerX"
                    type = NodeType.ROLLERX;
                    
                case "RollerY"
                    type = NodeType.ROLLERY;
                    
                case "RollerZ"
                    type = NodeType.ROLLERZ;  
                    
                case "RollerXY"
                    type = NodeType.ROLLERXY;
                    
                case "RollerXZ"
                    type = NodeType.ROLLERXZ;
                    
                case "RollerYZ"
                    type = NodeType.ROLLERYZ;
                    
            end
            
            if (app.ui.settings.getGeometricSpace == "2D")
            
                nodeAux = Node(coordinates(1),coordinates(2));
            
            else
                
                nodeAux = Node(coordinates(1),coordinates(2),coordinates(3));
                
            end
            
            condition = 0;
            
            for i = 1:length(app.ui.nodes)             
                
                if (nodeAux.getCoordinates() == app.ui.nodes{i}.getCoordinates())
                    condition = 1;
                    break;
                end
                
            end
            
            if (condition)
            
                error = Error(2,"This node already exists!");
                error.consoleDisplay();
            
            else

                app.ui.addNode(nodeAux,type); 
                app.boundaryNodesList{end+1} = type;
                num = length(app.Nodes.Children)+1;
                uitreenode(app.Nodes,'Text',strcat("Node "+num2str(num)," (",type2,")"));
                app.updateAxes();

            end
            
        end
        
        function addMaterialValue(app,data)
            
            app.materials{end+1} = data; 
            uitreenode(app.MaterialsNode,'Text',data{1});
            
        end
        
        function addNewElement(app,type,index,data)
            
            index = index(:,1)';
            condition = 1;
            
            len = 0;
            switch type
                case 'TRI3'
                    len = 3;
                    
                case 'QUAD4'
                    len = 4;
                    
                case 'QUAD8'
                    len = 8;
                    
                case 'HEX8'
                    len = 8;
                    
            end
            
            if (length(index) == len)
            
                for i = 1:length(app.ui.elements)
                    
                    type2 = NaN;
                    switch class(app.ui.elements{i})
                        case 'TRI3'
                            type2 = ElementType.TRI3;
                            
                        case 'QUAD4'
                            type2 = ElementType.QUAD4;
                            
                        case 'QUAD8'
                            type2 = ElementType.QUAD8;
                            
                        case 'HEX8'
                            type2 = ElementType.QUAD8;
                        
                    end
                    
                    if (type == type2)
                        
                        if (index == app.ui.elements{i}.getIndex())
                            
                            error = Error(3,"This element alreadry exists!");
                            error.consoleDisplay();
                            condition = 0;
                            break;
                            
                        end
                    
                    end
                    
                end
                
                if (condition)
                    
                    app.elements{end+1} = {type index data}; 
                    num = length(app.ElementsNode.Children)+1;
                    uitreenode(app.ElementsNode,'Text',"Element "+num2str(num));
                    
                    app.ui.addElement(type,index,data);
                    
                    app.updateLines();
                    
                end
   
                
            else 
                
                error = Error(1,"Invalid nodes quantity!");
                error.consoleDisplay();
                
            end
                
            app.updateAxes();
            
        end
        
        function addNewForce(app,node,orientation,magnitude,sense)
            
            app.ui.addForce(node,orientation,magnitude,sense);
            app.boundaryForcesList{end+1} = {node,orientation,magnitude,sense};
            uitreenode(app.ForcesNode_2,'Text',strcat("Force (",num2str(length(app.boundaryForcesList)),") = ",num2str(magnitude*sense)));
            app.updateAxes();
            
        end
        
    end
        
    methods (Access = private)
        
        function updateLines(app)
            
            app.lines = [];
            
            for j = 1:length(app.elements)
                
                type = app.elements{j};  % Selecionando o elemento
                type = type{1};         % Selecionando o index do elemento
                
                index = app.elements{j};  % Selecionando o elemento
                index = index{2};         % Selecionando o index do elemento
                
                len = length(index);
                
                if (type == ElementType.HEX8)
                    len = 12;
                    linesAux = [1 2; 2 3; 3 4; 4 8; 5 6; 6 7; 7 8; 1 5; 2 6; 3 7; 4 1; 8 5];
                end
                
                for i = 1:len
                    
                    line = [];
                    
                    if (type == ElementType.HEX8)
                        
                        line = linesAux(i,:);
                       
                    else
                        
                        if (i == length(index))    
                            line = [index(i) index(1)];
                        else
                            line = [index(i) index(i+1)];
                        end
                        
                    end
                    
                    if (isempty(app.lines))
                        
                        app.lines(end+1,:) = line;
                        
                    else 
                        
                        condition = 1;
                        
                        for k = 1:length(app.lines(:,1))
                           
                            aux = app.lines(k,:);
                            
                            if ((((aux(1) == line(1)) && (aux(2) == line(2))) || ((aux(2) == line(1)) && (aux(1) == line(2)))))
                            
                                condition = 0;
                                break;
                            
                            end
                            
                        end
                        
                        if (condition)
                            
                            app.lines(end+1,:) = line;
                            
                        end
                        
                    end
                    
                end
                
            end


            %{ 
            linesClone = [];
            
            for j = 1:length(app.elements)
                
                index = app.elements{j};
                index = index{2};
            
                for i = 1:length(index)
                            
                    if (i == length(index))
                        line = [index(i) index(1)];
                    else 
                        line = [index(i) index(i+1)];
                    end
                
                    if (~(isempty(app.lines)))
                            
                        condition2 = 1;
                        if (i == length(index))
                            line = [index(i) index(1)];
                        else 
                            line = [index(i) index(i+1)];
                        end
    
                        for j = 1:length(app.lines)-1
                            
                            if ((line(1) == app.lines(j,1)) && (line(2) == app.lines(j,2)) || (line(1) == app.lines(j,2)) && (line(2) == app.lines(j,1)))
                                condition2 = 0;
                                break;
                            end
                            
                        end
                       
                        if (condition2)
    
                            linesClone(end+1,:) = line;
                            
                        end
                        
                    else 
                        
                        linesClone(end+1,:) = line;
                    
                    end
                
                end
                
            end 
            
            app.lines = linesClone;

            %}
            
        end
        
        function updateAxes(app)
            
            cla(app.UIAxes);
            hold(app.UIAxes,"on");
            
            if (app.ui.settings.getGeometricSpace() == "2D")
            
                [xLim,yLim] = app.axesLimits();
                
                app.UIAxes.XLim = xLim;
                app.UIAxes.YLim = yLim;
                
                nodes = app.ui.getNodes();
                
                if (~(isempty(app.boundaryForcesList)) && (isempty(app.displacements)))
                    
                    for i = 1:length(app.boundaryForcesList)
                       
                       force = app.boundaryForcesList{i};
                       % {node,orientation,magnitude,sense}
                       
                       size = 0.1;
                       sense = force{4};
                       
                       nodes = app.ui.nodes;
                       position = [];
                       
                       node = nodes{force{1}};
                       node = node.getCoordinates();
                       
                       switch force{2}
                           case "x"
                               
                               position = [node(1) node(2);
                                           node(1)+sense*size node(2);
                                           node(1)+sense*size-0.25*sense*size node(2)+0.025;
                                           node(1)+sense*size-0.25*sense*size node(2)-0.025];
                               color = "#0072BD";
                               
                           case "y"
                               
                               position = [node(1) node(2);
                                           node(1) node(2)+sense*size;
                                           node(1)+0.025 node(2)+sense*size-0.1*sense*size;
                                           node(1)-0.025 node(2)+sense*size-0.1*sense*size];
                               color = "#EDB120";
                               
                       end
                        
                       plot(app.UIAxes,position(1,1),position(1,2),"Color",color,"MarkerSize",2);
                       plot(app.UIAxes,position(2,1),position(2,2),"Color",color,"MarkerSize",2);
                       
                       plot(app.UIAxes,[position(1,1) position(2,1)],[position(1,2) position(2,2)],"Color",color,"LineWidth",1);
                       plot(app.UIAxes,[position(2,1) position(3,1)],[position(2,2) position(3,2)],"Color",color,"LineWidth",1);
                       plot(app.UIAxes,[position(2,1) position(4,1)],[position(2,2) position(4,2)],"Color",color,"LineWidth",1); 
                       
                    end
                    
                end
                
                % Printando os nós:
                
                    for i = 1:length(nodes)
                        node = nodes{i}.getCoordinates();
                        
                        color = "";
                        switch app.boundaryNodesList{i}
                            case NodeType.PINNED
                                color = "k";
                                
                            case NodeType.FREE
                                color = "#77AC30";
                                
                            case NodeType.ROLLERX
                                color = "#7E2F8E";
                                
                            case NodeType.ROLLERY
                                color = "#D95319";
                                
                        end
                        
                        plot(app.UIAxes,node(1),node(2),"Marker",".","Color",color,"MarkerSize",12);
                        text(app.UIAxes,node(1)+0.05,node(2)+0.05,strcat("Node ",num2str(i)));
                    end
    
                if (~(isempty(app.lines)))
                    
                    for i = 1:length(app.lines)
                    
                        line = app.lines(i,:);
                        
                        node1 = nodes{line(1)}.getCoordinates();
                        node2 = nodes{line(2)}.getCoordinates();
                        
                        plot(app.UIAxes,[node1(1),node2(1)],[node1(2),node2(2)],"Color",'k',"LineStyle","--");
                    
                    end
                    
                end
                
                if (~(isempty(app.displacements)))
                    
                    newNodes = [];
                    auxNodes = app.ui.getNodes();
                    size = 0.1;
                    
                    maxDisp = max(abs(app.displacements));
                    
                    for k = 1:length(auxNodes)
                       
                        auxNode = auxNodes{k}.getCoordinates();
                        newNodes(k,:) = [auxNode(1)+size*(app.displacements(k*2-1)/maxDisp) auxNode(2)+size*(app.displacements(k*2)/maxDisp)];
                        
                    end
                    
                    for i = 1:length(app.lines)
                    
                        line = app.lines(i,:);
                        
                        node1 = newNodes(line(1),:);
                        node2 = newNodes(line(2),:);
                        
                        plot(app.UIAxes,[node1(1),node2(1)],[node1(2),node2(2)],"Color",'r',"LineStyle","--");
                    
                    end
                    
                end
            
            else
                
                [xLim,yLim,zLim] = app.axesLimits3D();
                
                app.UIAxes.XLim = xLim;
                app.UIAxes.YLim = yLim;
                app.UIAxes.ZLim = zLim;
                
                nodes = app.ui.getNodes();
                
                % Printando os nós:
                
                for i = 1:length(nodes)
                    node = nodes{i}.getCoordinates();
                    
                    color = "";
                    switch app.boundaryNodesList{i}
                        case NodeType.PINNED
                            color = "k";
                            
                        case NodeType.FREE
                            color = [.7 .7 .7];
                            
                        case NodeType.ROLLERX
                            color = [1 0 0];
                            
                        case NodeType.ROLLERY
                            color = [0 1 0];
                            
                        case NodeType.ROLLERZ
                            color = [0 0 1];
                            
                        case NodeType.ROLLERXY
                            color = [1 1 0];
                            
                        case NodeType.ROLLERXZ
                            color = [1 0 1];
                            
                        case NodeType.ROLLERYZ
                            color = [0 1 1];
                            
                    end
                    
                    plot3(app.UIAxes,node(1),node(2),node(3),"Marker",".","Color",color,"MarkerSize",12);
                    text(app.UIAxes,node(1)+0.15,node(2)+0.15,node(3)+0.15,strcat("Node ",num2str(i)));
                end
                
                % Printando as linhas:
                
                if (~(isempty(app.lines)))
                    
                    for i = 1:length(app.lines)
                    
                        line = app.lines(i,:);
                        
                        node1 = nodes{line(1)}.getCoordinates();
                        node2 = nodes{line(2)}.getCoordinates();
                        
                        plot3(app.UIAxes,[node1(1),node2(1)],[node1(2),node2(2)],[node1(3),node2(3)],"Color",'k',"LineStyle","--");
                    
                    end
                    
                end
                
                % Printando as forças
                
                if (~(isempty(app.boundaryForcesList)) && (isempty(app.displacements)))
                    
                    for i = 1:length(app.boundaryForcesList)
                       
                       force = app.boundaryForcesList{i};
                       % {node,orientation,magnitude,sense}
                       
                       size = 0.6;
                       sense = force{4};
                       
                       nodes = app.ui.nodes;
                       position = [];
                       
                       node = nodes{force{1}};
                       node = node.getCoordinates();
                       
                       switch force{2}
                           case "x"
                               
                               position = [node(1) node(2) node(3);
                                           node(1)+sense*size node(2) node(3);
                                           node(1)+sense*size-0.1*sense*size node(2)+0.025 node(3);
                                           node(1)+sense*size-0.1*sense*size node(2)-0.025 node(3)];
                               color = "#0072BD";
                               
                           case "y"
                               
                               position = [node(1) node(2) node(3);
                                           node(1) node(2)+sense*size node(3);
                                           node(1)+0.025 node(2)+sense*size-0.1*sense*size node(3);
                                           node(1)-0.025 node(2)+sense*size-0.1*sense*size node(3)];
                               color = "#EDB120";
                               
                           case "z"
                               
                               position = [node(1) node(2) node(3);
                                           node(1) node(2) node(3)+sense*size;
                                           node(1)+0.025 node(2) node(3)+sense*size-0.1*sense*size;
                                           node(1)-0.025 node(2) node(3)+sense*size-0.1*sense*size];
                               color = "r";
                               
                       end
                        
                       plot3(app.UIAxes,position(1,1),position(1,2),position(1,3),"Color",color,"MarkerSize",2);
                       plot3(app.UIAxes,position(2,1),position(2,2),position(2,3),"Color",color,"MarkerSize",2);
                       
                       plot3(app.UIAxes,[position(1,1) position(2,1)],[position(1,2) position(2,2)],[position(1,3) position(2,3)],"Color",color,"LineWidth",1);
                       plot3(app.UIAxes,[position(2,1) position(3,1)],[position(2,2) position(3,2)],[position(2,3) position(3,3)],"Color",color,"LineWidth",1);
                       plot3(app.UIAxes,[position(2,1) position(4,1)],[position(2,2) position(4,2)],[position(2,3) position(4,3)],"Color",color,"LineWidth",1); 
                       
                    end
                    
                end
                
                % Deformada:
                
                if (~(isempty(app.displacements)))
                    
                    newNodes = [];
                    auxNodes = app.ui.getNodes();
                    size = 0.5;
                    
                    maxDisp = max(abs(app.displacements));
                    
                    for k = 1:length(auxNodes)
                       
                        auxNode = auxNodes{k}.getCoordinates();
                        newNodes(k,:) = [auxNode(1)+size*(app.displacements(k*3-2)/maxDisp) auxNode(2)+size*(app.displacements(k*3-1)/maxDisp) auxNode(3)+size*(app.displacements(k*3)/maxDisp)];
                        
                    end
                    
                    for i = 1:length(app.lines)
                    
                        line = app.lines(i,:);
                        
                        node1 = newNodes(line(1),:);
                        node2 = newNodes(line(2),:);
                        
                        plot3(app.UIAxes,[node1(1),node2(1)],[node1(2),node2(2)],[node1(3),node2(3)],"Color",'r',"LineStyle","--");
                    
                    end
                    
                end
                
            end
            
        end
        
        function [xLim,yLim] = axesLimits(app)
            
            x = []; y = [];
            
            for i = 1:length(app.ui.nodes)
                
               node = app.ui.nodes{i}.getCoordinates();
               
               x(end+1) = node(1);
               y(end+1) = node(2);
                
            end
            
            xLim = [floor((app.margin*-1)+min(x)) ceil((app.margin*1)+max(x))];
            yLim = [floor((app.margin*-1)+min(y))  ceil((app.margin*1)+max(y))];
            
        end
        
        function [xLim,yLim,zLim] = axesLimits3D(app)
            
            x = []; y = []; z = [];
            
            for i = 1:length(app.ui.nodes)
                
               node = app.ui.nodes{i}.getCoordinates();
               
               x(end+1) = node(1);
               y(end+1) = node(2);
               z(end+1) = node(2);
                
            end
            
            xLim = [floor((app.margin*-1)+min(x)) ceil((app.margin*1)+max(x))];
            yLim = [floor((app.margin*-1)+min(y))  ceil((app.margin*1)+max(y))];
            zLim = [floor((app.margin*-1)+min(z))  ceil((app.margin*1)+max(z))];
            
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            app.ui.setGeometricSpace("2D");
            
        end

        % Clicked callback: addNodeBtn
        function addNodeBtnClicked(app, event)
            
            if (app.ui.settings.getGeometricSpace() == "2D")
               
                addNodeView(app);
            
            else 
                
                addNodeView3D(app);
                
            end
                
        end

        % Menu selected function: AddMaterial
        function AddMaterialSelected(app, event)
            
            if (app.ui.settings.getGeometricSpace() == "2D")
               
                addMaterialView(app);
            
            else 
                
                addMaterialView3D(app);
                
            end
            
        end

        % Clicked callback: addElementBtn
        function addElementBtnClicked(app, event)
            
            if (app.ui.settings.getGeometricSpace() == "2D")
            
                addElementView(app);
                
            else
                
                addElementView3D(app);
                
            end
            
        end

        % Menu selected function: AddElementMenu
        function AddElementMenuSelected(app, event)
            
            if (app.ui.settings.getGeometricSpace() == "2D")
            
                addElementView(app);
                
            else
                
                addElementView3D(app);
                
            end
            
        end

        % Menu selected function: AddNodeMenu
        function AddNodeMenuSelected(app, event)
            
            if (app.ui.settings.getGeometricSpace() == "2D")
               
                addNodeView(app);
            
            else 
                
                addNodeView3D(app);
                
            end
            
        end

        % Clicked callback: PushTool
        function PushToolClicked(app, event)
            
            if (app.ui.settings.getGeometricSpace() == "2D")
               
                addMaterialView(app);
            
            else 
                
                addMaterialView3D(app);
                
            end
            
            
        end

        % Clicked callback: PushTool2
        function PushTool2Clicked(app, event)
            
            
            if (app.ui.settings.getGeometricSpace() == "2D")
            
                addForceView(app);
                
            else
                
                addForceView3D(app);
                
            end
            
        end

        % Menu selected function: AddForceMenu
        function AddForceMenuSelected(app, event)
            
            if (app.ui.settings.getGeometricSpace() == "2D")
            
                addForceView(app);
                
            else
                
                addForceView3D(app);
                
            end
            
        end

        % Clicked callback: PushTool3
        function PushTool3Clicked(app, event)
            
            if (~(isempty(app.forces)))
                
                app.forces = [];
                app.displacements = [];
                
            end
            
            [app.forces,app.displacements] = app.ui.solve();
            
            for i = 1:length(app.forces)
                
                uitreenode(app.ForcesNode,'Text',strcat("Force (",num2str(i),") = ",num2str(app.forces(i))));
                uitreenode(app.DisplacementsNode,'Text',strcat("𝛿 (",num2str(i),") = ",num2str(app.displacements(i))));
            
            end
            
            app.updateAxes();
            
        end

        % Menu selected function: RunModelCalculateMenu
        function RunModelCalculateMenuSelected(app, event)
            
            if (~(isempty(app.forces)))
                
                app.forces = [];
                app.displacements = [];
                
            end
            
            [app.forces,app.displacements] = app.ui.solve();
            
            for i = 1:length(app.forces)
                
                uitreenode(app.ForcesNode,'Text',strcat("Force (",num2str(i),") = ",num2str(app.forces(i))));
                uitreenode(app.DisplacementsNode,'Text',strcat("𝛿 (",num2str(i),") = ",num2str(app.displacements(i))));
            
            end
            
            app.updateAxes();
            
        end

        % Menu selected function: RestartAppMenu
        function RestartAppMenuSelected(app, event)
            
            delete(app.ui);
            app.ui = UI();
            app.UIAxes.reset;
            grid(app.UIAxes, "on");
            app.ToggleTool.State = "off";
            cla(app.UIAxes);
                        
            % Atualizando Settings:
            
                app.ui.setGeometricSpace("2D");
            
            % Limpar trees
            
            app.MaterialsNode.Children.delete;
            app.Nodes.Children.delete;
            app.ElementsNode.Children.delete;
            app.ForcesNode.Children.delete;
            app.ForcesNode_2.Children.delete;
            app.DisplacementsNode.Children.delete;
            
            % Resetar MainView data
            
            app.materials = {};
            app.boundaryForcesList = [];
            app.boundaryNodesList = [];
            app.forces = [];
            app.displacements = [];
            app.elements = {};
            app.lines = [];
            app.lastElementType = NaN;
            app.lastMaterial = NaN;
            app.margin = 0.25;
            
        end

        % On callback: ToggleTool
        function ToggleToolOn(app, event)
            
            % Aplicando o estilo padrão:

                plot3(app.UIAxes, 0, 0, 0, 'color', [1 1 1]);
                grid(app.UIAxes, "on");
                        
            % Atualizando Settings:
            
                app.ui.settings.setGeometricSpace("3D");


        end

        % Off callback: ToggleTool
        function ToggleToolOff(app, event)
            
            % Aplicando o estilo padrão:

                app.UIAxes.reset;
                grid(app.UIAxes, "on");
                        
            % Atualizando Settings:
            
                app.ui.setGeometricSpace("2D");
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OptionsMenu = uimenu(app.UIFigure);
            app.OptionsMenu.Text = 'Options';
            app.RestartAppMenu = uimenu(app.OptionsMenu);
            app.RestartAppMenu.MenuSelectedFcn = createCallbackFcn(app, @RestartAppMenuSelected, true);
            app.RestartAppMenu.Text = 'Restart App';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.PushTool.Tooltip = {'Add Material'};
            app.PushTool.ClickedCallback = createCallbackFcn(app, @PushToolClicked, true);
            app.PushTool.Icon = 'book.png';
            app.addNodeBtn = uipushtool(app.Toolbar);
            app.addNodeBtn.Tooltip = {'Add Node'};
            app.addNodeBtn.ClickedCallback = createCallbackFcn(app, @addNodeBtnClicked, true);
            app.addNodeBtn.Icon = 'node.png';
            app.addElementBtn = uipushtool(app.Toolbar);
            app.addElementBtn.Tooltip = {'Add Element'};
            app.addElementBtn.ClickedCallback = createCallbackFcn(app, @addElementBtnClicked, true);
            app.addElementBtn.Icon = 'link (1).png';
            app.PushTool2 = uipushtool(app.Toolbar);
            app.PushTool2.Tooltip = {'Add Force'};
            app.PushTool2.ClickedCallback = createCallbackFcn(app, @PushTool2Clicked, true);
            app.PushTool2.Icon = 'gravitation.png';
            app.PushTool3 = uipushtool(app.Toolbar);
            app.PushTool3.Tooltip = {'Run Model (Calculate)'};
            app.PushTool3.ClickedCallback = createCallbackFcn(app, @PushTool3Clicked, true);
            app.PushTool3.Icon = 'play-button.png';
            app.ToggleTool = uitoggletool(app.Toolbar);
            app.ToggleTool.Tooltip = {'Toggle 2D/3D'};
            app.ToggleTool.Icon = '3d-printer.png';
            app.ToggleTool.Separator = 'on';
            app.ToggleTool.OffCallback = createCallbackFcn(app, @ToggleToolOff, true);
            app.ToggleTool.OnCallback = createCallbackFcn(app, @ToggleToolOn, true);
            app.UIAxes = uiaxes(app.UIFigure);
            app.Tree = uitree(app.UIFigure);
            app.MaterialsNode = uitreenode(app.Tree);
            app.MaterialsNode.Text = 'Materials';
            app.Nodes = uitreenode(app.Tree);
            app.Nodes.Text = 'Nodes';
            app.ElementsNode = uitreenode(app.Tree);
            app.ElementsNode.Text = 'Elements';
            app.ForcesNode_2 = uitreenode(app.Tree);
            app.ForcesNode_2.Text = 'Forces';
            app.ResultsNode = uitreenode(app.Tree);
            app.ResultsNode.Text = 'Results';
            app.ForcesNode = uitreenode(app.ResultsNode);
            app.ForcesNode.Text = 'Forces';
            app.DisplacementsNode = uitreenode(app.ResultsNode);
            app.DisplacementsNode.Text = 'Displacements';
            app.StressNode = uitreenode(app.ResultsNode);
            app.StressNode.Text = 'Stress';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.AddMaterial = uimenu(app.ContextMenu);
            app.AddMaterial.MenuSelectedFcn = createCallbackFcn(app, @AddMaterialSelected, true);
            app.AddMaterial.Text = 'Add Material';
            % Assign app.ContextMenu
            app.MaterialsNode.ContextMenu = app.ContextMenu;
            app.ContextMenu2 = uicontextmenu(app.UIFigure);
            app.AddElementMenu = uimenu(app.ContextMenu2);
            app.AddElementMenu.MenuSelectedFcn = createCallbackFcn(app, @AddElementMenuSelected, true);
            app.AddElementMenu.Text = 'Add Element';
            % Assign app.ContextMenu2
            app.ElementsNode.ContextMenu = app.ContextMenu2;
            app.ContextMenu3 = uicontextmenu(app.UIFigure);
            app.AddNodeMenu = uimenu(app.ContextMenu3);
            app.AddNodeMenu.MenuSelectedFcn = createCallbackFcn(app, @AddNodeMenuSelected, true);
            app.AddNodeMenu.Text = 'Add Node';
            % Assign app.ContextMenu3
            app.Nodes.ContextMenu = app.ContextMenu3;
            app.ContextMenu4 = uicontextmenu(app.UIFigure);
            app.AddForceMenu = uimenu(app.ContextMenu4);
            app.AddForceMenu.MenuSelectedFcn = createCallbackFcn(app, @AddForceMenuSelected, true);
            app.AddForceMenu.Text = 'Add Force';
            % Assign app.ContextMenu4
            app.ForcesNode_2.ContextMenu = app.ContextMenu4;
            app.ContextMenu5 = uicontextmenu(app.UIFigure);
            app.RunModelCalculateMenu = uimenu(app.ContextMenu5);
            app.RunModelCalculateMenu.MenuSelectedFcn = createCallbackFcn(app, @RunModelCalculateMenuSelected, true);
            app.RunModelCalculateMenu.Text = 'Run Model (Calculate)';
            % Assign app.ContextMenu5
            app.ResultsNode.ContextMenu = app.ContextMenu5;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MainView

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end