classdef addForceView3D < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Panel                      matlab.ui.container.Panel
        SenseDropDown              matlab.ui.control.DropDown
        SenseLabel                 matlab.ui.control.Label
        MagnitudeEditField         matlab.ui.control.NumericEditField
        MagnitudeLabel             matlab.ui.control.Label
        OrientationDropDown        matlab.ui.control.DropDown
        OrientationLabel           matlab.ui.control.Label
        NodeEditField              matlab.ui.control.NumericEditField
        NodeEditFieldLabel         matlab.ui.control.Label
        CancelarButton             matlab.ui.control.Button
        AdicionarButton            matlab.ui.control.Button
        InsiraaspropriedadesLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        MainView % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, view)
            
            app.MainView = view;
            
            if (isempty(view.boundaryNodesList))
                
                error = Error(5,"There's no node added!");
                error.consoleDisplay();
                delete(app);
                
            end
            
        end

        % Button pushed function: CancelarButton
        function CancelarButtonPushed(app, event)
            
            delete(app);
            
        end

        % Button pushed function: AdicionarButton
        function AdicionarButtonPushed(app, event)
            
            node = app.NodeEditField.Value;
            orientation = "";
            
            switch app.OrientationDropDown.Value
                case "X-axis"
                    orientation = "x";
                    
                case "Y-axis"
                    orientation = "y";
                
                case "Z-axis"
                    orientation = "z";
                    
            end
            
            sense = 0;
            switch app.SenseDropDown.Value
                case "Positive"
                    sense = 1;
               
                case "Negative"
                    sense = -1;
                    
            end
            
            magnitude = app.MagnitudeEditField.Value;
            
            app.MainView.addNewForce(node,orientation,magnitude,sense);
            
            delete(app);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.InsiraaspropriedadesLabel = uilabel(app.UIFigure);
            app.InsiraaspropriedadesLabel.Text = {'Insira as propriedades:'; ''};
            app.AdicionarButton = uibutton(app.UIFigure, 'push');
            app.AdicionarButton.ButtonPushedFcn = createCallbackFcn(app, @AdicionarButtonPushed, true);
            app.AdicionarButton.Text = 'Adicionar';
            app.CancelarButton = uibutton(app.UIFigure, 'push');
            app.CancelarButton.ButtonPushedFcn = createCallbackFcn(app, @CancelarButtonPushed, true);
            app.CancelarButton.Text = 'Cancelar';
            app.Panel = uipanel(app.UIFigure);
            app.NodeEditFieldLabel = uilabel(app.Panel);
            app.NodeEditFieldLabel.Text = 'Node:';
            app.NodeEditField = uieditfield(app.Panel, 'numeric');
            app.NodeEditField.RoundFractionalValues = 'on';
            app.OrientationLabel = uilabel(app.Panel);
            app.OrientationLabel.Text = 'Orientation:';
            app.OrientationDropDown = uidropdown(app.Panel);
            app.OrientationDropDown.Items = {'X-axis', 'Y-axis', 'Z-axis'};
            app.OrientationDropDown.Value = 'X-axis';
            app.MagnitudeLabel = uilabel(app.Panel);
            app.MagnitudeLabel.Text = 'Magnitude:';
            app.MagnitudeEditField = uieditfield(app.Panel, 'numeric');
            app.SenseLabel = uilabel(app.Panel);
            app.SenseLabel.Text = 'Sense:';
            app.SenseDropDown = uidropdown(app.Panel);
            app.SenseDropDown.Items = {'Positive', 'Negative'};
            app.SenseDropDown.Value = 'Positive';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = addForceView3D(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end