classdef addElementView < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        Panel                  matlab.ui.container.Panel
        TipoDropDown           matlab.ui.control.DropDown
        TipoDropDownLabel      matlab.ui.control.Label
        IndexEditField         matlab.ui.control.EditField
        IndexEditFieldLabel    matlab.ui.control.Label
        MaterialDropDown       matlab.ui.control.DropDown
        MaterialDropDownLabel  matlab.ui.control.Label
        CancelarButton         matlab.ui.control.Button
        AdicionarButton        matlab.ui.control.Button
        InsiraaspropriedadesdoelementoLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        MainView % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, view)
            
            app.MainView = view;
            
            if (isempty(view.materials))
                
                error = Error(4,"There's no material added!");
                error.consoleDisplay();
                delete(app);

            else 
              
                materials = [""];
                for i = 1:length(view.materials)
                    material = view.materials{i};
                    materials(i) = material{1};
                end
                
                app.MaterialDropDown.Items = materials;
                app.MaterialDropDown.ItemsData = 1:length(material);
                
                if (~isnan(view.lastMaterial))
                    app.MaterialDropDown.Value = view.lastMaterial;
                end
                
                if (~isnan(view.lastElementType))
                    app.TipoDropDown.Value = view.lastElementType;
                end
            
            end
            
        end

        % Button pushed function: CancelarButton
        function CancelarButtonPushed(app, event)
            
            delete(app);
            
        end

        % Button pushed function: AdicionarButton
        function AdicionarButtonPushed(app, event)
            
            material = app.MaterialDropDown.Value;
            material = app.MainView.materials{material};
            
            E = material{2};
            v = material{3};
            t = material{4};
            
            type = app.TipoDropDown.Value;
            switch type
                case "TRI3"
                    type = ElementType.TRI3;
                
                case "QUAD4"
                    type = ElementType.QUAD4;
                    
                case "QUAD8"
                    type = ElementType.QUAD8;
                    
            end
            
            string = split(app.IndexEditField.Value,',');
            index = zeros(length(string));
            for i = 1:length(string)
                index(i) = str2double(string(i));
            end
            
            data = [E,v,t];
            app.MainView.addNewElement(type,index,data);
            
            app.MainView.lastMaterial = app.MaterialDropDown.Value;
            app.MainView.lastElementType = app.TipoDropDown.Value;
            
            delete(app);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.InsiraaspropriedadesdoelementoLabel = uilabel(app.UIFigure);
            app.InsiraaspropriedadesdoelementoLabel.Text = {'Insira as propriedades do elemento:'; ''};
            app.AdicionarButton = uibutton(app.UIFigure, 'push');
            app.AdicionarButton.ButtonPushedFcn = createCallbackFcn(app, @AdicionarButtonPushed, true);
            app.AdicionarButton.Text = 'Adicionar';
            app.CancelarButton = uibutton(app.UIFigure, 'push');
            app.CancelarButton.ButtonPushedFcn = createCallbackFcn(app, @CancelarButtonPushed, true);
            app.CancelarButton.Text = 'Cancelar';
            app.Panel = uipanel(app.UIFigure);
            app.MaterialDropDownLabel = uilabel(app.Panel);
            app.MaterialDropDownLabel.Text = 'Material:';
            app.MaterialDropDown = uidropdown(app.Panel);
            app.IndexEditFieldLabel = uilabel(app.Panel);
            app.IndexEditFieldLabel.Text = 'Index:';
            app.IndexEditField = uieditfield(app.Panel, 'text');
            app.IndexEditField.Tooltip = {'Os nós devem ser acrescentados em ordem anti-horária e separados por vírgulas.'};
            app.IndexEditField.Placeholder = '1,2,3...';
            app.TipoDropDownLabel = uilabel(app.Panel);
            app.TipoDropDownLabel.Text = 'Tipo:';
            app.TipoDropDown = uidropdown(app.Panel);
            app.TipoDropDown.Items = {'TRI3', 'QUAD4', 'QUAD8'};
            app.TipoDropDown.Value = 'TRI3';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = addElementView(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end